classdef ChessAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        chessboardfig     matlab.ui.container.GridLayout
        Button_A8         matlab.ui.control.StateButton
        Button_B8         matlab.ui.control.StateButton
        Button_C8         matlab.ui.control.StateButton
        Button_D8         matlab.ui.control.StateButton
        Button_E8         matlab.ui.control.StateButton
        Button_F8         matlab.ui.control.StateButton
        Button_G8         matlab.ui.control.StateButton
        Button_H8         matlab.ui.control.StateButton
        Button_A7         matlab.ui.control.StateButton
        Button_B7         matlab.ui.control.StateButton
        Button_C7         matlab.ui.control.StateButton
        Button_D7         matlab.ui.control.StateButton
        Button_E7         matlab.ui.control.StateButton
        Button_F7         matlab.ui.control.StateButton
        Button_G7         matlab.ui.control.StateButton
        Button_H7         matlab.ui.control.StateButton
        Button_A6         matlab.ui.control.StateButton
        Button_B6         matlab.ui.control.StateButton
        Button_C6         matlab.ui.control.StateButton
        Button_D6         matlab.ui.control.StateButton
        Button_E6         matlab.ui.control.StateButton
        Button_F6         matlab.ui.control.StateButton
        Button_G6         matlab.ui.control.StateButton
        Button_H6         matlab.ui.control.StateButton
        Button_A5         matlab.ui.control.StateButton
        Button_B5         matlab.ui.control.StateButton
        Button_C5         matlab.ui.control.StateButton
        Button_D5         matlab.ui.control.StateButton
        Button_E5         matlab.ui.control.StateButton
        Button_F5         matlab.ui.control.StateButton
        Button_G5         matlab.ui.control.StateButton
        Button_H5         matlab.ui.control.StateButton
        Button_A4         matlab.ui.control.StateButton
        Button_B4         matlab.ui.control.StateButton
        Button_C4         matlab.ui.control.StateButton
        Button_D4         matlab.ui.control.StateButton
        Button_E4         matlab.ui.control.StateButton
        Button_F4         matlab.ui.control.StateButton
        Button_G4         matlab.ui.control.StateButton
        Button_H4         matlab.ui.control.StateButton
        Button_A3         matlab.ui.control.StateButton
        Button_B3         matlab.ui.control.StateButton
        Button_C3         matlab.ui.control.StateButton
        Button_D3         matlab.ui.control.StateButton
        Button_E3         matlab.ui.control.StateButton
        Button_F3         matlab.ui.control.StateButton
        Button_G3         matlab.ui.control.StateButton
        Button_H3         matlab.ui.control.StateButton
        Button_A2         matlab.ui.control.StateButton
        Button_B2         matlab.ui.control.StateButton
        Button_C2         matlab.ui.control.StateButton
        Button_D2         matlab.ui.control.StateButton
        Button_E2         matlab.ui.control.StateButton
        Button_F2         matlab.ui.control.StateButton
        Button_G2         matlab.ui.control.StateButton
        Button_H2         matlab.ui.control.StateButton
        Button_A1         matlab.ui.control.StateButton
        Button_B1         matlab.ui.control.StateButton
        Button_C1         matlab.ui.control.StateButton
        Button_D1         matlab.ui.control.StateButton
        Button_E1         matlab.ui.control.StateButton
        Button_F1         matlab.ui.control.StateButton
        Button_G1         matlab.ui.control.StateButton
        Button_H1         matlab.ui.control.StateButton
        Player1Label      matlab.ui.control.Label
        Player2Label      matlab.ui.control.Label
        GridLayout3       matlab.ui.container.GridLayout
        SUBMITMOVEButton  matlab.ui.control.Button
        Label_2           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label             matlab.ui.control.Label
        p2label           matlab.ui.control.Label
        p1label           matlab.ui.control.Label
        ContextMenu       matlab.ui.container.ContextMenu
        Menu              matlab.ui.container.Menu
        Menu2             matlab.ui.container.Menu
    end

    
    properties (Access = public)% these are variales that belong to the app, access by using dot notation
        old_icon;   %holds onto the icon of the button pushed, storing it allows us to transfer it to the next location
        stateofturn;    %this is a variable that correlates to whose turn it is (currently the messages switch for the suer, buteventually should add more error checking
        first_spot;     %This is for updating the board
        last_spot;      %this is used for updating the board
        Player1;        %becomes name of first player, player 1 is tbot if in AI mode
        Player2;        %player 2, or the user agasint the bot
        splayturn;      %will determine wether to grab the icon, or paste the old icon to new button
        board;          %matrix used to store the board value
        mode;           %determines to do AI or 2 plyer mode
        tempmes;        %used as a temp holder for when the submit buttton is pressed, allowing us to flip flop the current messages.
        firstpiece;     %this is the first piece in the scanning algorithm
        destpiece;      %this is the location in the scnannig algorithm
        firstpiecevalue; %determien the value on the matrix for the first piece in the scanning, starting piece
        finalloc ;      %this whill be a placeholder used to decide the location on the matrix from the given baord.
        inspot;      %user start lock is used for tarcking the user defined moves.
        inrow;      %inital row being mvoed
        incol;      %initial col being moved
        endspot;    %get the layout of the end spot
        endrow;      %end row being moved to
        endcol;     %end column bering mvoed to
        iconname;   %used to update matrix based on its corresponding values
        mypieces;   %count for pieces left
        readableboard   ;  %output of the scanboard function
        ranksboard;     %new board with ranks based off of the scanning
        whitepieces;      %count the number of white pieces on the board
        blackpieces;       %count the number of black pieces on the board
        options;        %output of get options
        startrow;       %
        endRow;         %
        startcol;       %
        endCol;         %
        selection;      %oyutput of make selection
        indexx1;
        indexy1;
        indexx2;
        indexy2;
        
        
        MrArduino;      %test
        
    end
    
    
    methods (Access = private)
        
        function readableboard = scanboard(app) %outputs the board with strings
            readableboard = cell(8,8);
            app.readableboard = readableboard;
            
            ranks = nonzeros(app.board);      %variableses that will be used in scanning the matrix and picking moves
            [row,column,ranks] = find(app.board);
            searches = nnz(app.board);
            
            
            maxrow = app.chessboardfig.RowHeight;
            dimensionsr = size(maxrow);
            Maxnumrow =   dimensionsr(2);
            
            maxcolumn = app.chessboardfig.ColumnWidth;
            dimensionsc = size(maxcolumn);
            Maxcolumn = dimensionsc(2);
            
         
            target = 0;
            
            for i=1:Maxnumrow
                for j=1:Maxcolumn
                    
                    if i == 1 && j ==1
                        target = app.Button_A8.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 1 && j ==2
                        target = app.Button_B8.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 1 && j ==3
                        target = app.Button_C8.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 1 && j ==4
                        target = app.Button_D8.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 1 && j ==5
                        target = app.Button_E8.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 1 && j ==6
                        target = app.Button_F8.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 1 && j ==7
                        target = app.Button_G8.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 1 && j ==8
                        target = app.Button_H8.Icon;
                        readableboard{i,j} = target;
                        
                        %scan and assign the next row
                        
                    elseif i == 2 && j ==1
                        target = app.Button_A7.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 2 && j ==2
                        target = app.Button_B7.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 2 && j ==3
                        target = app.Button_C7.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 2 && j ==4
                        target = app.Button_D7.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 2 && j ==5
                        target = app.Button_E7.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 2 && j ==6
                        target = app.Button_F7.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 2 && j ==7
                        target = app.Button_G7.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 2 && j ==8
                        target = app.Button_H7.Icon;
                        readableboard{i,j} = target;
                        
                        %scan and assign the next row
                        
                    elseif i == 3 && j ==1
                        target = app.Button_A6.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 3 && j ==2
                        target = app.Button_B6.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 3 && j ==3
                        target = app.Button_C6.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 3 && j ==4
                        target = app.Button_D6.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 3 && j ==5
                        target = app.Button_E6.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 3 && j ==6
                        target = app.Button_F6.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 3 && j ==7
                        target = app.Button_G6.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 3 && j ==8
                        target = app.Button_H6.Icon;
                        readableboard{i,j} = target;
                        
                        %scan and assign the next row
                        
                    elseif i == 4 && j ==1
                        target = app.Button_A5.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 4 && j ==2
                        target = app.Button_B5.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 4 && j ==3
                        target = app.Button_C5.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 4 && j ==4
                        target = app.Button_D5.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 4 && j ==5
                        target = app.Button_E5.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 4 && j ==6
                        target = app.Button_F5.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 4 && j ==7
                        target = app.Button_G5.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 4 && j ==8
                        target = app.Button_H5.Icon;
                        readableboard{i,j} = target;
                        
                        %scan and assign the next row
                        
                    elseif i == 5 && j ==1
                        target = app.Button_A4.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 5 && j ==2
                        target = app.Button_B4.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 5 && j ==3
                        target = app.Button_C4.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 5 && j ==4
                        target = app.Button_D4.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 5 && j ==5
                        target = app.Button_E4.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 5 && j ==6
                        target = app.Button_F4.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 5 && j ==7
                        target = app.Button_G4.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 5 && j ==8
                        target = app.Button_H4.Icon;
                        readableboard{i,j} = target;
                        
                        %scan and assign the next row
                        
                    elseif i == 6 && j ==1
                        target = app.Button_A3.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 6 && j ==2
                        target = app.Button_B3.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 6 && j ==3
                        target = app.Button_C3.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 6 && j ==4
                        target = app.Button_D3.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 6 && j ==5
                        target = app.Button_E3.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 6 && j ==6
                        target = app.Button_F3.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 6 && j ==7
                        target = app.Button_G3.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 6 && j ==8
                        target = app.Button_H3.Icon;
                        readableboard{i,j} = target;
                        %scan and assign the next row
                        
                    elseif i == 7 && j ==1
                        target = app.Button_A2.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 7 && j ==2
                        target = app.Button_B2.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 7 && j ==3
                        target = app.Button_C2.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 7 && j ==4
                        target = app.Button_D2.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 7 && j ==5
                        target = app.Button_E2.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 7 && j ==6
                        target = app.Button_F2.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 7 && j ==7
                        target = app.Button_G2.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 7 && j ==8
                        target = app.Button_H2.Icon;
                        readableboard{i,j} = target;
                        
                        %scan and assign the next row
                        
                    elseif i == 8 && j ==1
                        target = app.Button_A1.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 8 && j ==2
                        target = app.Button_B1.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 8 && j ==3
                        target = app.Button_C1.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 8 && j ==4
                        target = app.Button_D1.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 8 && j ==5
                        target = app.Button_E1.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 8 && j ==6
                        target = app.Button_F1.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 8 && j ==7
                        target = app.Button_G1.Icon;
                        readableboard{i,j} = target;
                        
                    elseif i == 8 && j ==8
                        target = app.Button_H1.Icon;
                        readableboard{i,j} = target;
                        
                    end
                end
            end
            app.readableboard = readableboard;
            
        end
        
        
        function ranksboard = getranksboard(app)    %scans the string board (that can be called whenever the icons are updated) and then outputs the ranks board.
            ranksboard = zeros(8,8);
          
            for i=1:8
                for j = 1:8
                    if app.readableboard(i,j) == "blackrook.png"
                        ranksboard(i,j) = 5.2;
                        app.ranksboard = ranksboard;
                        
                    elseif app.readableboard(i,j) == "blackknight.png"
                        ranksboard(i,j) = 3.2;
                        app.ranksboard = ranksboard;
                        
                    elseif app.readableboard(i,j) == "blackqueen.png"
                        ranksboard(i,j) = 9.2;
                        app.ranksboard = ranksboard;
                        
                    elseif app.readableboard(i,j) == "blackbishop.png"
                        ranksboard(i,j) = 4.2;
                        app.ranksboard = ranksboard ;
                        
                    elseif app.readableboard(i,j) =="blackpawn.png"
                        ranksboard(i,j) = 1.2;
                        app.ranksboard = ranksboard ;
                        
                    elseif app.readableboard(i,j) == "blackking.png"
                        ranksboard(i,j) = 99.2;
                        app.ranksboard = ranksboard ;
                        
                        %now consider case for player 1, white team
                    elseif app.readableboard(i,j) == "whiteknight.png"
                        ranksboard(i,j) = 3.1;
                        app.ranksboard = ranksboard;
                        
                    elseif app.readableboard(i,j) == "whitequeen.png"
                        ranksboard(i,j) = 9.1;
                        app.ranksboard = ranksboard ;
                        
                    elseif app.readableboard(i,j) == "whitebishop.png"
                        ranksboard(i,j) = 4.1;
                        app.ranksboard = ranksboard  ;
                        
                    elseif app.readableboard(i,j) == "whitepawn.png"
                        ranksboard(i,j) = 1.1;
                        app.ranksboard = ranksboard  ;
                        
                    elseif app.readableboard(i,j) == "whiterook.png"
                        ranksboard(i,j) = 5.1;
                        app.ranksboard = ranksboard;
                        
                    elseif app.readableboard(i,j) == "whiteking.png"
                        ranksboard(i,j) = 99.1;
                        app.ranksboard = ranksboard;
                    end
                end
            end
            app.ranksboard = ranksboard;
        end
        
        function whitepieces= getnumpieces(app)
            
            
            %need to determine the team by analyzing the last digit of the
            whitepieces = 0;
            app.blackpieces = 0;
           
            for i=1:8
                for j=1:8
                    
                    if app.ranksboard(i,j) == 9.1 || app.ranksboard(i,j) == 5.1 || app.ranksboard(i,j) == 3.1 || app.ranksboard(i,j) == 1.1 || app.ranksboard(i,j) == 99.1
                        
                        whitepieces = whitepieces+1;
                        
                        app.whitepieces = whitepieces;
                        
                    elseif app.ranksboard(i,j) == 9.2 || app.ranksboard(i,j) == 5.2 || app.ranksboard(i,j) == 3.2 || app.ranksboard(i,j) == 1.2 || app.ranksboard(i,j) == 99.2
                        
                        app.blackpieces = app.blackpieces +1;
                        
                        
                    end
                end
            end
            
            app.whitepieces = whitepieces;
            app.blackpieces = app.blackpieces;
            
        end
        
        function options = getoptions(app)
            %return the row and column of where a certain piece can move,
            %this is where I will need to include its functionality and
            %highlight those specific boxes
            options = app.options;
            %check for each specific piece at a time, and then go ahead and
            
            app.options = 0;
            app.endRow = zeros(0);
            app.endCol = zeros(0);
            app.startrow = zeros(0);
            app.startcol = zeros(0);
            
            i = 1;
            j =1;
            
            for i=1:8
                for j=1:8
                    %scan for each element
                    
                    if app.ranksboard(i,j) == 1.1   %case where a white pawn is found. get the potential options for this move
                        %we have a white pawn here
                        %we can look one or or two ahead if it is still in
                        %the same row it started in, or across and ahead
                        %one only if the spot is occupied by an opponent.
                        
                        
                        if i<8 && app.ranksboard(i+1,j) == 0 %nobody is infron of the pawn in the column
                            
                            app.options = app.options +1;
                            app.endRow(end+1) = i+1;
                            app.endCol(end+1) = j;
                            app.startrow(end+1) = i;
                            app.startcol(end+1) = j;
                        end
                        
                        if i == 2 && app.ranksboard(i+2,j) == 0  && app.ranksboard(i+1,j) ==0 %here is where we can move our pawn 2 up, when nobody is 2 squares in front and the pawn is still in the original row
                            app.endRow(end+1) = i+2;
                            app.endCol(end+1) = j;
                            
                            app.options = app.options +1;
                            app.startrow(end+1) = i;
                            app.startcol(end+1) = j;
                        end
                        
                        if  i <8 && j < 8 && (app.ranksboard(i+1,j+1) == 0 && app.ranksboard(i+1,j+1) == 1.2 && app.ranksboard(i+1,j+1) == 3.2 && app.ranksboard(i+1,j+1) == 5.2  && app.ranksboard(i+1,j+1) == 9.2 && app.ranksboard(i+1,j+1) == 99.2)
                            app.endRow(end+1) = i+1;
                            app.endCol(end+1) = j+1;
                            app.startrow(end+1) = i;
                            app.startcol(end+1) = j;
                            app.options = app.options+1;
                        end
                        
                        if i < 8&& j >1 && (app.ranksboard(i+1,j-1) == 0 && app.ranksboard(i+1,j-1) == 1.2 && app.ranksboard(i+1,j-1) == 3.2 && app.ranksboard(i+1,j-1) == 5.2  && app.ranksboard(i+1,j-1) == 9.2 && app.ranksboard(i+1,j-1) == 99.2)
                            app.endRow(end+1) = i+1;
                            app.endCol(end+1) = j-1;
                            app.startrow(end+1) = i;
                            app.startcol(end+1) = j;
                            app.options = app.options+1;
                        else
                            app.options = app.options;
                            
                        end
                        
                    elseif app.ranksboard(i,j) == 5.1
                        %the case of a white rook, it can go forwad and
                        %backwards and up/down in vertical and horizontal
                        
                        %lets handle forward moves first
                        %is the spot in question empty or occupied by the
                        %other team?
                       for place=1:8
                        if i+place <= 8 && (app.ranksboard(i+place,j) ==0 || app.ranksboard(i+place,j) ==1.2 ||app.ranksboard(i+place,j) ==3.2 || app.ranksboard(i+place,j) ==4.2 || app.ranksboard(i+place,j) ==5.2 || app.ranksboard(i+place,j) ==9.2 ||app.ranksboard(i+place,j) ==99.2) && all(sum(app.ranksboard(i+1:i+place-1,j))) ==0
                                
                                app.endRow(end+1) = i+place;
                                app.endCol(end+1) = j;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                
                        end
                        
                        %next lets give it the chance to go backwards
                        %work from previous code
                        if i-place >= 1 && (app.ranksboard(i-place,j) ==0 || app.ranksboard(i-place,j) == 1.2 || app.ranksboard(i-place,j) == 3.2 || app.ranksboard(i-place,j) == 4.2 || app.ranksboard(i-place,j) == 5.2 || app.ranksboard(i-place,j) == 9.2 || app.ranksboard(i-place,j) == 99.2) && all(sum(app.ranksboard(i-place+1:i-1,j))) ==0
                            app.endRow(end+1) = i-place;
                            app.endCol(end+1) = j;
                            app.startrow(end+1) = i;
                            app.startcol(end+1) = j;
                            app.options = app.options+1;
                            
                        end
                       end
                       
                        
                        %not lets get the rook moving right
                        
                        for place=1:8
                            if (j+place <=8 ) && (app.ranksboard(i,j+place) ==0 || app.ranksboard(i,j+place) == 1.2 || app.ranksboard(i,j+place) == 3.2 || app.ranksboard(i,j+place) == 4.2 || app.ranksboard(i,j+place) == 5.2 || app.ranksboard(i,j+place) == 9.2 || app.ranksboard(i,j+place) == 99.2) && all(sum(app.ranksboard(i,j+1:j+place-1))) ==0
                                app.endRow(end+1) = i;
                                app.endCol(end+1) = j+place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                            end
                            % to the left
                             
                            if (j-place >= 1) && (app.ranksboard(i,j-place)==0 || app.ranksboard(i,j-place) == 1.2 || app.ranksboard(i,j-place) == 3.2 || app.ranksboard(i,j-place) == 4.2 || app.ranksboard(i,j-place) == 5.2 || app.ranksboard(i,j-place) == 9.2 || app.ranksboard(i,j-place) == 99.2) && all(sum(app.ranksboard(i,j-place+1:j-1))) ==0
                                app.endRow(end+1) = i;
                                app.endCol(end+1) = j-place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                            end


                        end


                        %lets handle the bishop
                    elseif app.ranksboard(i,j) == 4.1
                        %the case of the white bishop!
                        %follow a similiar approach to the rook,
                        
                        %diagnoal up and to the right
                        spot =1;
                        for place=1:8
                            if ( i+place <=8  && j+ place <=8) &&  (app.ranksboard(i+place,j+place) == 0 || app.ranksboard(i,j+place) == 1.2 || app.ranksboard(i,j+place) == 3.2 || app.ranksboard(i,j+place) == 4.2 || app.ranksboard(i,j+place) == 5.2 || app.ranksboard(i,j+place) == 9.2 || app.ranksboard(i,j+place) == 99.2) && all(sum(app.ranksboard(i+1:i+place-1,j+1:j+place-1))) ==0
                                app.endRow(end+1) = i+place; 
                                app.endCol(end+1) = j+place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                            end
                            
                            %down and to the left

                            if ( i-place>=1 && j-place >1) &&  (app.ranksboard(i-place,j-place) ==0 || app.ranksboard(i-place,j-place) == 1.2 || app.ranksboard(i-place,j-place) == 3.2 || app.ranksboard(i-place,j-place) == 4.2 || app.ranksboard(i-place,j-place) == 5.2 || app.ranksboard(i-place,j-place) == 9.2 || app.ranksboard(i-place,j-place) == 99.2) && all(sum(app.ranksboard(i-place+1:i-1, j-place+1:j-1))) ==0
                                app.endRow(end+1) = i-place;
                                app.endCol(end+1) = j-place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                            end
                            
                            %up and to left bishop
                            
                            
                            if ( i+place <= 8  && j-place >=(1))  && (app.ranksboard(i+place,j-place) ==0 || app.ranksboard(i+place,j-place) == 1.2 || app.ranksboard(i+place,j-place) == 3.2 || app.ranksboard(i+place,j-place) == 4.2 || app.ranksboard(i+place,j-place) == 5.2 || app.ranksboard(i+place,j-place) == 9.2 || app.ranksboard(i+place,j-place) == 99.2) && all(sum(app.ranksboard(i+1:i+place-1,j-place+1:j-1))) == 0 
                                app.endRow(end+1) = i+place;
                                app.endCol(end+1) = j-place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                            end
                            
                            %down and to theright bishop
                            
                            if ( i-place >= 1 && j+place <= 8) && (app.ranksboard(i-place,j+place) ==0 || app.ranksboard(i-place,j+place) == 1.2 || app.ranksboard(i-place,j+place) == 3.2 || app.ranksboard(i-place,j+place) == 4.2 || app.ranksboard(i-place,j+place) == 5.2 || app.ranksboard(i-place,j+place) == 9.2 || app.ranksboard(i-place,j+place) == 99.2) && all(sum(app.ranksboard(i-place+1:i-1,j+1:j+place-1))) == 0
                                app.endRow(end+1) = i-place;
                                app.endCol(end+1) = j+place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                            end
                            
                            
                        end


                    %lets get the knights going next bby
                    %here is up and to the right (numerically)
                    %just got to check if the sppot being moved to is
                    %occupied by an enemy
                    elseif app.ranksboard(i,j) == 3.1
                        
                        %up and to the right , hotdog
                            if  i+2 <= 8 && j+1 <=8 && (app.ranksboard(i+2,j+1) == 0 ||  app.ranksboard(i+2,j+1)==1.2 ||  app.ranksboard(i+2,j+1) ==3.2 || app.ranksboard(i+2,j+1) ==4.2 ||app.ranksboard(i+2,j+1)==5.2  ||app.ranksboard(i+2,j+1) ==9.2 || app.ranksboard(i+2,j+1) == 99.2)
                                
                                app.endRow(end+1) = i+2;
                                app.endCol(end+1) = j+1;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                 
                            end
                            %up and to the right, hamburger
                            if  i+1 <= 8 && j+2 <= 8 && (app.ranksboard(i+1,j+2) == 0 ||  app.ranksboard(i+1,j+2)==1.2 ||  app.ranksboard(i+1,j+2) ==3.2 || app.ranksboard(i+1,j+2) ==4.2 ||app.ranksboard(i+1,j+2)==5.2  ||app.ranksboard(i+1,j+2) ==9.2 || app.ranksboard(i+1,j+2) == 99.2)
                                
                                app.endRow(end+1) = i+1;
                                app.endCol(end+1) = j+2;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                 
                            end
                            
                            %up and to the left,hotdog
                            if  i+2 <=8 && j-1>=1 && (app.ranksboard(i+2,j-1) == 0 ||  app.ranksboard(i+2,j-1)==1.2 ||  app.ranksboard(i+2,j-1) ==3.2 || app.ranksboard(i+2,j-1) ==4.2 ||app.ranksboard(i+2,j-1)==5.2  ||app.ranksboard(i+2,j-1) ==9.2 || app.ranksboard(i+2,j-1) == 99.2)
                                
                                app.endRow(end+1) = i+2;
                                app.endCol(end+1) = j-1;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                
                           %up and to the left,  hamburger
                            if  i+1 >= 1 && j-2 >= 1 && (app.ranksboard(i+1,j-2) == 0 ||  app.ranksboard(i+1,j-2)==1.2 ||  app.ranksboard(i+1,j-2) ==3.2 || app.ranksboard(i+1,j-2) ==4.2 ||app.ranksboard(i+1,j-2)==5.2  ||app.ranksboard(i+1,j-2) ==9.2 || app.ranksboard(i+1,j-2) == 99.2)
                                
                                app.endRow(end+1) = i+1;
                                app.endCol(end+1) = j-2;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                             
                             
                            end
                            end
                            
                            %down and to the right, hotdog
                            if  i-2 >= 1 && j+1 <= 8 && (app.ranksboard(i-2,j+1) == 0 ||  app.ranksboard(i-2,j+1)==1.2 ||  app.ranksboard(i-2,j+1) ==3.2 || app.ranksboard(i-2,j+1) ==4.2 ||app.ranksboard(i-2,j+1)==5.2  ||app.ranksboard(i-2,j+1) ==9.2 || app.ranksboard(i-2,j+1) == 99.2)
                                
                                app.endRow(end+1) = i-2;
                                app.endCol(end+1) = j+1;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                 
                            end
                            
                             %down and to the right, hamburger
                            if  i-1 >=1 && j+2 <=8 && (app.ranksboard(i-1,j+2) == 0 ||  app.ranksboard(i-1,j+2)==1.2 ||  app.ranksboard(i-1,j+2) ==3.2 || app.ranksboard(i-1,j+2) ==4.2 ||app.ranksboard(i-1,j+2)==5.2  ||app.ranksboard(i-1,j+2) ==9.2 || app.ranksboard(i+1,j+2) == 99.2)
                                
                                app.endRow(end+1) = i-1;
                                app.endCol(end+1) = j+2;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                 
                            end
                            

                            %downa and to the left hotdog
                            if  i-2 >=1 && j-1>=1 && (app.ranksboard(i-2,j-1) == 0  ||  app.ranksboard(i-2,j-1)==1.2 ||  app.ranksboard(i-2,j-1) ==3.2 || app.ranksboard(i-2,j-1) ==4.2 ||app.ranksboard(i-2,j-1)==5.2  ||app.ranksboard(i-2,j-1) ==9.2 || app.ranksboard(i-2,j-1) == 99.2)
                                
                                app.endRow(end+1) = i-2;
                                app.endCol(end+1) = j-1;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;

                            end
                            
                            %down and to the left hamburger             
                            if i-1 >=1 && j-2 >=1 && (app.ranksboard(i-1,j-2) == 0 ||  app.ranksboard(i-1,j-2)==1.2 ||  app.ranksboard(i-1,j-2) ==3.2 || app.ranksboard(i-1,j-2) ==4.2 ||app.ranksboard(i-1,j-2)==5.2  ||app.ranksboard(i-1,j-2) ==9.2 || app.ranksboard(i+1,j-2) == 99.2)
                                
                                app.endRow(end+1) = i-1; 
                                app.endCol(end+1) = j-2;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                
                            end
                        
                            %now we have the king and queen left, lets do
                            %kind (avoid mentioning checkmate condtions0
                            %so just 8 moves, one each way with the correct
                            %condtion
                          
                            
                            %kingtime 
                    elseif  app.ranksboard(i,j) == 99.1
                        %if king
                        %up
                        if i +1 <= 8 && (app.ranksboard(i+1,j) == 0 || app.ranksboard(i+1,j) == 1.2 || app.ranksboard(i+1,j) == 3.2 || app.ranksboard(i+1,j)== 5.2 ||app.ranksboard(i+1,j) == 9.2 || app.ranksboard(i+1,j) == 99.2)
                            app.endRow(end+1) = i+1;
                            app.endCol(end+1) = j;
                            app.startrow(end+1) = i;
                            app.startcol(end+1) = j;
                            app.options = app.options+1;
                            
                        end
                        %upright
                        
                         if i+1 <=8 && j+1 <= 8&& (app.ranksboard(i+1,j+1) == 0 || app.ranksboard(i+1,j+1) == 1.2 || app.ranksboard(i+1,j+1) == 3.2 || app.ranksboard(i+1,j+1)== 5.2 ||app.ranksboard(i+1,j+1) == 9.2 || app.ranksboard(i+1,j+1) == 99.2)
                             
                            app.endRow(end+1) = i+1;
                            app.endCol(end+1) = j+1;
                            app.startrow(end+1) = i;
                            app.startcol(end+1) = j;
                            app.options = app.options+1;
                            
                         end
                         
                         
                         %upleft
                         
                         if i +1 <=8 && j-1 >=1 && (app.ranksboard(i+1,j-1) == 0 || app.ranksboard(i+1,j-1) == 1.2 || app.ranksboard(i+1,j-1) == 3.2 || app.ranksboard(i+1,j-1)== 5.2 ||app.ranksboard(i+1,j-1) == 9.2 || app.ranksboard(i+1,j-1) == 99.2)
                             
                             app.endRow(end+1) = i+1;
                             app.endCol(end+1) = j-1;
                             app.startrow(end+1) = i;
                             app.startcol(end+1) = j;
                             app.options = app.options+1;
                         end
                         %down
                         
                         if i-1 >=1 && (app.ranksboard(i-1,j) == 0 || app.ranksboard(i-1,j) == 1.2 || app.ranksboard(i-1,j) == 3.2 || app.ranksboard(i-1,j)== 5.2 ||app.ranksboard(i-1,j) == 9.2 || app.ranksboard(i-1,j) == 99.2)
                             
                             app.endRow(end+1) = i-1;
                             app.endCol(end+1) = j;
                             app.startrow(end+1) = i;
                             app.startcol(end+1) = j;
                             app.options = app.options+1;
                         end
                         %right
                         
                         if j+1 <=8 && app.ranksboard(i,j+1) == 0
                             
                             app.endRow(end+1) = i;
                             app.endCol(end+1) = j+1;
                             app.startrow(end+1) = i;
                             app.startcol(end+1) = j;
                             app.options = app.options+1;
                         end
                         
                         %downleft
                         
                         if i-1 >= 1 && j-1>= 1 && (app.ranksboard(i-1,j-1) == 0 || app.ranksboard(i-1,j-1) == 1.2|| app.ranksboard(i-1,j-1) == 3.2 || app.ranksboard(i-1,j-1) == 4.2 || app.ranksboard(i-1,j-1) == 5.2 || app.ranksboard(i-1,j-1) == 9.2 || app.ranksboard(i-1,j-1) == 99.2)
                             
                             
                             app.endRow(end+1) = i-1;
                             app.endCol(end+1) = j-1;
                             app.startrow(end+1) = i;
                             app.startcol(end+1) = j;
                             app.options = app.options+1;
                             
                         end
                         
                         %toleft
                         
                         if j-1 >=1 && (app.ranksboard(i,j-1) == 0 || app.ranksboard(i,j-1) == 1.2|| app.ranksboard(i,j-1) == 3.2 || app.ranksboard(i,j-1) == 4.2 || app.ranksboard(i,j-1) == 5.2 || app.ranksboard(i,j-1) == 9.2 || app.ranksboard(i,j-1) == 99.2)
                             
                             app.endRow(end+1) = i;
                             app.endCol(end+1) = j-1;
                             app.startrow(end+1) = i;
                             app.startcol(end+1) = j;
                             app.options = app.options+1;
                         end
                         
                         %downright
                         
                         if i-1 >=1 && j+2 <=8 && (app.ranksboard(i-1,j+1) == 0  || app.ranksboard(i-1,j+1) == 1.2|| app.ranksboard(i-1,j+1) == 3.2 || app.ranksboard(i-1,j+1) == 4.2 || app.ranksboard(i-1,j+1) == 5.2 || app.ranksboard(i-1,j+1) == 9.2 || app.ranksboard(i-1,j+1) == 99.2)
                             
                             app.endRow(end+1) = i-1;
                             app.endCol(end+1) = j+1;
                             app.startrow(end+1) = i;
                             app.startcol(end+1) = j;
                             app.options = app.options+1;
                         end
                         
                         
                         %queen,combines power of rook and bishop
                         %bascially.
                         
                    elseif app.ranksboard(i,j) == 9.1
                        %queen

                        for place = 1:8
                            
                            %straightfwd
                            if i+place <= 8 && (app.ranksboard(i+place,j) ==0 || app.ranksboard(i+place,j) ==1.2 ||app.ranksboard(i+place,j) ==3.2 || app.ranksboard(i+place,j) ==4.2 || app.ranksboard(i+place,j) ==5.2 || app.ranksboard(i+place,j) ==9.2 ||app.ranksboard(i+place,j) ==99.2) && all(sum(app.ranksboard(i+1:i+place-1,j))) ==0
                                
                                app.endRow(end+1) = i+place;
                                app.endCol(end+1) = j;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                
                               
                            end
                            %straightback
                            if i-place >= 1 && (app.ranksboard(i-place,j) ==0 || app.ranksboard(i-place,j) == 1.2 || app.ranksboard(i-place,j) == 3.2 || app.ranksboard(i-place,j) == 4.2 || app.ranksboard(i-place,j) == 5.2 || app.ranksboard(i-place,j) == 9.2 || app.ranksboard(i-place,j) == 99.2) && all(sum(app.ranksboard(i-place+1:i-1,j))) ==0
                                app.endRow(end+1) = i-place;
                                app.endCol(end+1) = j;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                
                            end
                            
                            %to the right
                            if j+place <=8 && (app.ranksboard(i,j+place) ==0 || app.ranksboard(i,j+place) == 1.2 || app.ranksboard(i,j+place) == 3.2 || app.ranksboard(i,j+place) == 4.2 || app.ranksboard(i,j+place) == 5.2 || app.ranksboard(i,j+place) == 9.2 || app.ranksboard(i,j+place) == 99.2) && all(sum(app.ranksboard(j+1:j+place-1)))==0
                                app.endRow(end+1) = i;
                                app.endCol(end+1) = j+place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                
                            end
                            
                            %to the left
                            if j-place >=1 && (app.ranksboard(i,j-place) ==0 || app.ranksboard(i,j-place) == 1.2 || app.ranksboard(i,j-place) == 3.2 || app.ranksboard(i,j-place) == 4.2 || app.ranksboard(i,j-place) == 5.2 || app.ranksboard(i,j-place) == 9.2 || app.ranksboard(i,j-place) == 99.2) && all(sum(app.ranksboard(i,j-place+1:j-1)))==0
                                app.endRow(end+1) = i;
                                app.endCol(end+1) = j-place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                
                            end
                            
                            %downtoright
                            if i-place >= 1 && j+place <=8 && (app.ranksboard(i-place,j+place) ==0 || app.ranksboard(i-place,j+place) == 1.2 || app.ranksboard(i-place,j+place) == 3.2 || app.ranksboard(i-place,j+place) == 4.2 || app.ranksboard(i-place,j+place) == 5.2 || app.ranksboard(i-place,j+place) == 9.2 || app.ranksboard(i-place,j+place) == 99.2) && all(sum(app.ranksboard(i-place+1:i-1,j+1:j+place-1))) == 0
                                app.endRow(end+1) = i-place;
                                app.endCol(end+1) = j+place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                drtest = all(sum(app.ranksboard(i-place+1:i-1,j+1:j+place-1)))
                                
                            end
                            
                            %down to the left 
                            if i-place >= 1 && j-place >=1 &&  j+place <=8 && (app.ranksboard(i-place,j-place) ==0 || app.ranksboard(i-place,j-place) == 1.2 || app.ranksboard(i-place,j-place) == 3.2 || app.ranksboard(i-place,j-place) == 4.2 || app.ranksboard(i-place,j-place) == 5.2 || app.ranksboard(i-place,j-place) == 9.2 || app.ranksboard(i-place,j-place) == 99.2) && all(sum(app.ranksboard(i-place+1:i-1,j-place+1:j-1))) == 0
                                app.endRow(end+1) = i-place;
                                app.endCol(end+1) = j-place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                dltest = all(sum(app.ranksboard(i-place+1:i-1,j-place+1:j-1)))
                            end
                            
                            %upright
                            
                             if i+place <= 8 && j+place <=8 && (app.ranksboard(i+place,j+place) ==0 || app.ranksboard(i+place,j+place) == 1.2 || app.ranksboard(i+place,j+place) == 3.2 || app.ranksboard(i+place,j+place) == 4.2 || app.ranksboard(i+place,j+place) == 5.2 || app.ranksboard(i+place, j+place) == 9.2 || app.ranksboard(i+place,j+place) == 99.2) && all(sum(app.ranksboard(i+1:i+place-1,j+1:j+place-1))) ==0
                                app.endRow(end+1) = i+place;
                                app.endCol(end+1) = j+place;
                                app.startrow(end+1) = i;
                                app.startcol(end+1) = j;
                                app.options = app.options+1;
                                
                                urtest = all(sum(app.ranksboard(i+1:i+place-1,j+1:j+place-1)))
                                
                             end
                             
                             %upleft
                             
                           
                             if i+place <= 8 && j-place >=1 && (app.ranksboard(i+place,j-place) ==0 || app.ranksboard(i+place,j-place) == 1.2 || app.ranksboard(i+place,j-place) == 3.2 || app.ranksboard(i+place,j-place) == 4.2 || app.ranksboard(i+place,j-place) == 5.2 || app.ranksboard(i+place,j-place) == 9.2 || app.ranksboard(i+place,j-place) == 99.2) && all(sum(app.ranksboard(i+1:i+place-1,j-place+1:j-1))) == 0 
                                 app.endRow(end+1) = i+place;
                                 app.endCol(end+1) = j-place;
                                 app.startrow(end+1) = i;
                                 app.startcol(end+1) = j;
                                 app.options = app.options+1;
                                 
                                 ultest = all(sum(app.ranksboard(i+1:i+place-1,j-place+1:j-1)))
                                  
                             end
                            
                             
                        end
                        
                    end
                end
                    
            end
            movesavailable = app.options
            end
      
        
        function selection = makemove(app)  %now that I have the moves, just pick one randomly too start, and then updatew the board accordingly!
            selection = 0;
            if app.options >1
                app.selection = randi(app.options);%I will evntually add a rank system to go ro right along with options
                for i=1:app.options
                    if app.ranksboard(app.endRow(app.options),app.endCol(app.options)) == 1.2 || app.ranksboard(app.endRow(app.options),app.endCol(app.options)) == 3.2 || app.ranksboard(app.endRow(app.options),app.endCol(app.options)) == 5.2 || app.ranksboard(app.endRow(app.options),app.endCol(app.options)) == 9.2 || app.ranksboard(app.endRow(app.options),app.endCol(app.options)) == 99.2
                        app.selection = i;
                    end
                end
                    
            else
                app.selection = 1;
            end
            numMoveSelected = app.selection
            %now this is the input for another sub function that will
            %handle updating the board correctly.
            %the task here is quickly getting the button that the start row
            %and column indicate.
            %get button by matching the row and column to the button
            app.indexx1 = app.startrow(app.selection);
            app.indexy1 = app.startcol(app.selection);
            app.indexx2 = app.endRow(app.selection);
            app.indexy2 = app.endCol(app.selection);
            pause(1)    %This is to replicate the robot decidong on the next move
            if app.indexx1 == 1 && app.indexy1==1
                tempicon = app.Button_A8.Icon;
                set(app.Button_A8, 'Icon', 'remove');
                
            elseif app.indexx1 == 1 && app.indexy1==2
                tempicon = app.Button_B8.Icon;
                set(app.Button_B8, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 1 && app.indexy1==3
                tempicon = app.Button_C8.Icon;
                set(app.Button_C8, 'Icon', 'remove');
                
            elseif app.indexx1 == 1 && app.indexy1==4
                tempicon = app.Button_D8.Icon;
                set(app.Button_D8, 'Icon', 'remove');
                
            elseif app.indexx1 == 1 && app.indexy1==5
                tempicon = app.Button_E8.Icon;
                set(app.Button_E8, 'Icon', 'remove');
                
            elseif app.indexx1 == 1 && app.indexy1==6
                tempicon = app.Button_F8.Icon;
                set(app.Button_F8, 'Icon', 'remove');
                
            elseif app.indexx1 == 1 && app.indexy1==7
                tempicon = app.Button_G8.Icon;
                set(app.Button_G8, 'Icon', 'remove');
                
            elseif app.indexx1 == 1 && app.indexy1==8
                tempicon = app.Button_H8.Icon;
                set(app.Button_H8, 'Icon', 'remove');
                
                %now onto the next row
            elseif app.indexx1 == 2 && app.indexy1==1
                tempicon = app.Button_A7.Icon;
                set(app.Button_A7, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 2 && app.indexy1==2
                tempicon = app.Button_B7.Icon;
                set(app.Button_B7, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 2 && app.indexy1==3
                tempicon = app.Button_C7.Icon;
                set(app.Button_C7, 'Icon', 'remove');
                
            elseif app.indexx1 == 2 && app.indexy1==4
                tempicon = app.Button_D7.Icon;
                set(app.Button_D7, 'Icon', 'remove');
                
            elseif app.indexx1 == 2 && app.indexy1==5
                tempicon = app.Button_E7.Icon;
                set(app.Button_E7, 'Icon', 'remove');
                
            elseif app.indexx1 == 2 && app.indexy1==6
                tempicon = app.Button_F7.Icon;
                set(app.Button_F7, 'Icon', 'remove');
                
            elseif app.indexx1 == 2 && app.indexy1==7
                tempicon = app.Button_G7.Icon;
                set(app.Button_G7, 'Icon', 'remove');
                
            elseif app.indexx1 == 2 && app.indexy1==8
                tempicon = app.Button_H7.Icon;
                set(app.Button_H7, 'Icon', 'remove');
                
                %now onto the next row
            elseif app.indexx1 == 3 && app.indexy1==1
                tempicon = app.Button_A6.Icon;
                set(app.Button_A6, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 3 && app.indexy1==2
                tempicon = app.Button_B6.Icon;
                set(app.Button_B6, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 3 && app.indexy1==3
                tempicon = app.Button_C6.Icon;
                set(app.Button_C6, 'Icon', 'remove');
                
            elseif app.indexx1 == 3 && app.indexy1==4
                tempicon = app.Button_D6.Icon;
                set(app.Button_D6, 'Icon', 'remove');
                
            elseif app.indexx1 == 3 && app.indexy1==5
                tempicon = app.Button_E6.Icon;
                set(app.Button_E6, 'Icon', 'remove');
                
            elseif app.indexx1 == 3 && app.indexy1==6
                tempicon = app.Button_F6.Icon;
                set(app.Button_F6, 'Icon', 'remove');
                
            elseif app.indexx1 == 3 && app.indexy1==7
                tempicon = app.Button_G6.Icon;
                set(app.Button_G6, 'Icon', 'remove');
                
            elseif app.indexx1 == 3 && app.indexy1==8
                tempicon = app.Button_H6.Icon;
                set(app.Button_H6, 'Icon', 'remove');
                
                %now onto the next row
            elseif app.indexx1 == 4 && app.indexy1==1
                tempicon = app.Button_A5.Icon;
                set(app.Button_A5, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 4 && app.indexy1==2
                tempicon = app.Button_B5.Icon;
                set(app.Button_B5, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 4 && app.indexy1==3
                tempicon = app.Button_C5.Icon;
                set(app.Button_C5, 'Icon', 'remove');
                
            elseif app.indexx1 == 4 && app.indexy1==4
                tempicon = app.Button_D5.Icon;
                set(app.Button_D5, 'Icon', 'remove');
                
            elseif app.indexx1 == 4 && app.indexy1==5
                tempicon = app.Button_E5.Icon;
                set(app.Button_E5, 'Icon', 'remove');
                
            elseif app.indexx1 == 4 && app.indexy1==6
                tempicon = app.Button_F5.Icon;
                set(app.Button_F5, 'Icon', 'remove');
                
            elseif app.indexx1 == 4 && app.indexy1==7
                tempicon = app.Button_G5.Icon;
                set(app.Button_G5, 'Icon', 'remove');
                
            elseif app.indexx1 == 4 && app.indexy1==8
                tempicon = app.Button_H5.Icon;
                set(app.Button_H5, 'Icon', 'remove');
                
                
                %now onto the next row
            elseif app.indexx1 == 5 && app.indexy1==1
                tempicon = app.Button_A4.Icon;
                set(app.Button_A4, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 5 && app.indexy1==2
                tempicon = app.Button_B4.Icon;
                set(app.Button_B4, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 5 && app.indexy1==3
                tempicon = app.Button_C4.Icon;
                set(app.Button_C4, 'Icon', 'remove');
                
            elseif app.indexx1 == 5 && app.indexy1==4
                tempicon = app.Button_D4.Icon;
                set(app.Button_D4, 'Icon', 'remove');
                
            elseif app.indexx1 == 5 && app.indexy1==5
                tempicon = app.Button_E4.Icon;
                set(app.Button_E4, 'Icon', 'remove');
                
            elseif app.indexx1 == 5 && app.indexy1==6
                tempicon = app.Button_F4.Icon;
                set(app.Button_F4, 'Icon', 'remove');
                
            elseif app.indexx1 == 5 && app.indexy1==7
                tempicon = app.Button_G4.Icon;
                set(app.Button_G4, 'Icon', 'remove');
                
            elseif app.indexx1 == 5 && app.indexy1==8
                tempicon = app.Button_H4.Icon;
                set(app.Button_H4, 'Icon', 'remove');
                
                %now onto the next row
            elseif app.indexx1 == 6 && app.indexy1==1
                tempicon = app.Button_A3.Icon;
                set(app.Button_A3, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 6 && app.indexy1==2
                tempicon = app.Button_B3.Icon;
                set(app.Button_B3, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 6 && app.indexy1==3
                tempicon = app.Button_C3.Icon;
                set(app.Button_C3, 'Icon', 'remove');
                
            elseif app.indexx1 == 6 && app.indexy1==4
                tempicon = app.Button_D3.Icon;
                set(app.Button_D3, 'Icon', 'remove');
                
            elseif app.indexx1 == 6 && app.indexy1==5
                tempicon = app.Button_E3.Icon;
                set(app.Button_E3, 'Icon', 'remove');
                
            elseif app.indexx1 == 6 && app.indexy1==6
                tempicon = app.Button_F3.Icon;
                set(app.Button_F3, 'Icon', 'remove');
                
            elseif app.indexx1 == 6 && app.indexy1==7
                tempicon = app.Button_G3.Icon;
                set(app.Button_G3, 'Icon', 'remove');
                
            elseif app.indexx1 == 6 && app.indexy1==8
                tempicon = app.Button_H3.Icon;
                set(app.Button_H3, 'Icon', 'remove');
                
                %now onto the next row
            elseif app.indexx1 == 7 && app.indexy1==1
                tempicon = app.Button_A2.Icon;
                set(app.Button_A2, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 7 && app.indexy1==2
                tempicon = app.Button_B2.Icon;
                set(app.Button_B2, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 7 && app.indexy1==3
                tempicon = app.Button_C2.Icon;
                set(app.Button_C2, 'Icon', 'remove');
                
            elseif app.indexx1 == 7 && app.indexy1==4
                tempicon = app.Button_D2.Icon;
                set(app.Button_D2, 'Icon', 'remove');
                
            elseif app.indexx1 == 7 && app.indexy1==5
                tempicon = app.Button_E2.Icon;
                set(app.Button_E2, 'Icon', 'remove');
                
            elseif app.indexx1 == 7 && app.indexy1==6
                tempicon = app.Button_F2.Icon;
                set(app.Button_F2, 'Icon', 'remove');
                
            elseif app.indexx1 == 7 && app.indexy1==7
                tempicon = app.Button_G2.Icon;
                set(app.Button_G2, 'Icon', 'remove');
                
            elseif app.indexx1 == 7 && app.indexy1==8
                tempicon = app.Button_H2.Icon;
                set(app.Button_H2, 'Icon', 'remove');
                
                %now onto the next row
            elseif app.indexx1 == 8 && app.indexy1==1
                tempicon = app.Button_A1.Icon;
                set(app.Button_A1, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 8 && app.indexy1==2
                tempicon = app.Button_B1.Icon;
                set(app.Button_B1, 'Icon', 'remove')   ;
                
            elseif app.indexx1 == 8 && app.indexy1==3
                tempicon = app.Button_C1.Icon;
                set(app.Button_C1, 'Icon', 'remove');
                
            elseif app.indexx1 == 8 && app.indexy1==4
                tempicon = app.Button_D1.Icon;
                set(app.Button_D1, 'Icon', 'remove');
                
            elseif app.indexx1 == 8 && app.indexy1==5
                tempicon = app.Button_E1.Icon;
                set(app.Button_E1, 'Icon', 'remove');
                
            elseif app.indexx1 == 8 && app.indexy1==6
                tempicon = app.Button_F1.Icon;
                set(app.Button_F1, 'Icon', 'remove');
                
            elseif app.indexx1 == 8 && app.indexy1==7
                tempicon = app.Button_G1.Icon;
                set(app.Button_G1, 'Icon', 'remove');
                
            elseif app.indexx1 == 8 && app.indexy1==8
                tempicon = app.Button_H1.Icon;
                set(app.Button_H1, 'Icon', 'remove');
   
            end
            
             %now I will check and adjust the icon of the second
                %cordinate, from the endRow and End Col var
            pause(1)
            if app.indexx2 == 1 && app.indexy2==1
               
                set(app.Button_A8, 'Icon', tempicon);
                
            elseif app.indexx2 == 1 && app.indexy2==2
                
                set(app.Button_B8, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 1 && app.indexy2==3
                
                set(app.Button_C8, 'Icon', tempicon);
                
            elseif app.indexx2 == 1 && app.indexy2==4
               
                set(app.Button_D8, 'Icon', tempicon);
                
            elseif app.indexx2 == 1 && app.indexy2==5
                
                set(app.Button_E8, 'Icon', tempicon);
                
            elseif app.indexx2 == 1 && app.indexy2==6
                
                set(app.Button_F8, 'Icon', tempicon);
                
            elseif app.indexx2 == 1 && app.indexy2==7
               
                set(app.Button_G8, 'Icon', tempicon);
                
            elseif app.indexx2 == 1 && app.indexy2==8
               
                set(app.Button_H8, 'Icon', tempicon);
                
                %now onto the next row
            elseif app.indexx2 == 2 && app.indexy2==1
                
                set(app.Button_A7, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 2 && app.indexy2==2
                
                set(app.Button_B7, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 2 && app.indexy2==3
                
                set(app.Button_C7, 'Icon', tempicon);
                
            elseif app.indexx2 == 2 && app.indexy2==4
                
                set(app.Button_D7, 'Icon', tempicon);
                
            elseif app.indexx2 == 2 && app.indexy2==5
                
                set(app.Button_E7, 'Icon', tempicon);
                
            elseif app.indexx2 == 2 && app.indexy2==6
                
                set(app.Button_F7, 'Icon', tempicon);
                
            elseif app.indexx2 == 2 && app.indexy2==7
                
                set(app.Button_G7, 'Icon', tempicon);
                
            elseif app.indexx2 == 2 && app.indexy2==8
                
                set(app.Button_H7, 'Icon', tempicon);
                
                %now onto the next row
            elseif app.indexx2 == 3 && app.indexy2==1
                
                set(app.Button_A6, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 3 && app.indexy2==2
                
                set(app.Button_B6, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 3 && app.indexy2==3
                
                set(app.Button_C6, 'Icon', tempicon);
                
            elseif app.indexx2 == 3 && app.indexy2==4
                
                set(app.Button_D6, 'Icon', tempicon);
                
            elseif app.indexx2 == 3 && app.indexy2==5
                
                set(app.Button_E6, 'Icon', tempicon);
                
            elseif app.indexx2 == 3 && app.indexy2==6
                
                set(app.Button_F6, 'Icon', tempicon);
                
            elseif app.indexx2 == 3 && app.indexy2==7
                
                set(app.Button_G6, 'Icon', tempicon);
                
            elseif app.indexx2 == 3 && app.indexy2==8
                
                set(app.Button_H6, 'Icon', tempicon);
                
                %now onto the next row
            elseif app.indexx2 == 4 && app.indexy2==1
                
                set(app.Button_A5, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 4 && app.indexy2==2
                
                set(app.Button_B5, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 4 && app.indexy2==3
                
                set(app.Button_C5, 'Icon', tempicon);
                
            elseif app.indexx2 == 4 && app.indexy2==4
                
                set(app.Button_D5, 'Icon', tempicon);
                
            elseif app.indexx2 == 4 && app.indexy2==5
                
                set(app.Button_E5, 'Icon', tempicon);
                
            elseif app.indexx2 == 4 && app.indexy2==6
                
                set(app.Button_F5, 'Icon', tempicon);
                
            elseif app.indexx2 == 4 && app.indexy2==7
                
                set(app.Button_G5, 'Icon', tempicon);
                
            elseif app.indexx2 == 4 && app.indexy2==8
                
                set(app.Button_H5, 'Icon', tempicon);
                
                
                %now onto the next row
            elseif app.indexx2 == 5 && app.indexy2==1
                
                set(app.Button_A4, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 5 && app.indexy2==2
                
                set(app.Button_B4, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 5 && app.indexy2==3
                
                set(app.Button_C4, 'Icon', tempicon);
                
            elseif app.indexx2 == 5 && app.indexy2==4
                
                set(app.Button_D4, 'Icon', tempicon);
                
            elseif app.indexx2 == 5 && app.indexy2==5
                
                set(app.Button_E4, 'Icon', tempicon);
                
            elseif app.indexx2 == 5 && app.indexy2==6
                
                set(app.Button_F4, 'Icon', tempicon);
                
            elseif app.indexx2 == 5 && app.indexy2==7
                
                set(app.Button_G4, 'Icon', tempicon);
                
            elseif app.indexx2 == 5 && app.indexy2==8
                
                set(app.Button_H4, 'Icon', tempicon);
                
                %now onto the next row
            elseif app.indexx2 == 6 && app.indexy2==1
                
                set(app.Button_A3, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 6 && app.indexy2==2
                
                set(app.Button_B3, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 6 && app.indexy2==3
                
                set(app.Button_C3, 'Icon', tempicon);
                
            elseif app.indexx2 == 6 && app.indexy2==4
                
                set(app.Button_D3, 'Icon', tempicon);
                
            elseif app.indexx2 == 6 && app.indexy2==5
                
                set(app.Button_E3, 'Icon', tempicon);
                
            elseif app.indexx2 == 6 && app.indexy2==6
                
                set(app.Button_F3, 'Icon', tempicon);
                
            elseif app.indexx2 == 6 && app.indexy2==7
                
                set(app.Button_G3, 'Icon', tempicon);
                
            elseif app.indexx2 == 6 && app.indexy2==8
                
                set(app.Button_H3, 'Icon', tempicon);
                
                %now onto the next row
            elseif app.indexx2 == 7 && app.indexy2==1
                
                set(app.Button_A2, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 7 && app.indexy2==2
                
                set(app.Button_B2, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 7 && app.indexy2==3
                
                set(app.Button_C2, 'Icon', tempicon);
                
            elseif app.indexx2 == 7 && app.indexy2==4
                
                set(app.Button_D2, 'Icon', tempicon);
                
            elseif app.indexx2 == 7 && app.indexy2==5
                
                set(app.Button_E2, 'Icon', tempicon);
                
            elseif app.indexx2 == 7 && app.indexy2==6
                
                set(app.Button_F2, 'Icon', tempicon);
                
            elseif app.indexx2 == 7 && app.indexy2==7
                
                set(app.Button_G2, 'Icon', tempicon);
                
            elseif app.indexx2 == 7 && app.indexy2==8
                
                set(app.Button_H2, 'Icon', tempicon);
                
                %now onto the next row
            elseif app.indexx2 == 8 && app.indexy2==1
                
                set(app.Button_A1, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 8 && app.indexy2==2
                
                set(app.Button_B1, 'Icon', tempicon)   ;
                
            elseif app.indexx2 == 8 && app.indexy2==3
                
                set(app.Button_C1, 'Icon', tempicon);
                
            elseif app.indexx2 == 8 && app.indexy2==4
                
                set(app.Button_D1, 'Icon', tempicon);
                
            elseif app.indexx2 == 8 && app.indexy2==5
                
                set(app.Button_E1, 'Icon', tempicon);
                
            elseif app.indexx2 == 8 && app.indexy2==6
                
                set(app.Button_F1, 'Icon', tempicon);
                
            elseif app.indexx2 == 8 && app.indexy2==7
                
                set(app.Button_G1, 'Icon', tempicon);
                
            elseif app.indexx1 == 8 && app.indexy2==8
                
                set(app.Button_H1, 'Icon', tempicon);
             
                %but now that I made a piece, I should get a new ranks
                %board 
                app.readableboard(app)
                app.ranksboard(app)
                
            end
            %now change the labels since the board is updated by swapping
            %the messages or just copy code from earleir, should have made
            %it into a function
            app.tempmes = app.p1label.Text;      %save whatever is currently in the player 1 message box
            app.p1label.Text = app.p2label.Text;         %replace it with whatever is in the player 2 message box
            app.p2label.Text = app.tempmes;          %swap by placing the origainal saved variable for player 1 and putting it into player 2s message box

           
        end
        

        function machine = motorcontrol(app)
           if app.ranksboard(app.indexx2,app.indexy2) ==~ 0
               takePiece(app)
               movePiece(app)
           else
                movePiece(app)
           end


         machine =0 ;
    end


    function results = movePiece(app)
        %set output variable
        results = 0      ;
        
        %clear and create the object
        clear a
        a = arduino();
        
        %create object from z motion, servo
        s = servo(a,'D4',"MinPulseDuration",7.000e-4,"MaxPulseDuration",.00125);
        
        %define the down and up delay
        downDelay = 2.294;
        upDelay = 4.0;
        
        % define the pins for x stepepr
        stepPin = 'D6';
        dirPin = 'D26';
        
        %assign the pins for y stepper, assing the tag 1 after name
        stepPin1 = 'D3';
        dirPin1 = 'D24';
        
        %configrue pin modes for x stepper1
        configurePin(a,dirPin,'DigitalOutput')
        
        %confugure pin modes for y stepper
        configurePin(a,dirPin1,'DigitalOutput')
        
        %set a constant value for the steps to transpose 1 square
        kx = 16;
        ky = 22;
        
        %set the bias, since i move based on index -1, bias is to first
        %square.
        yBias = 15;
        xBias = 0;
        
        %set the x and y speed, but writing the delay
        xdelay = .00000000000000000000000000000000000000000000000000000009;
        ydelay = .00000000000000000000000000000000000000000000000000000002;
        
        
        %getmoving,first do an error check and move the machine home more
        writeDigitalPin(a,dirPin,0)
        for spotx=1:10
            
            
            %set the direction of xstepper
            writeDigitalPin(a,stepPin,1)
            pause(xdelay)
            writeDigitalPin(a,stepPin,0)
            pause(xdelay)
        end
        
        %same erroer check for y stepper
        writeDigitalPin(a,dirPin1,0)
        for spotx=1:10
            
            
            %set the direction of xstepper
            writeDigitalPin(a,stepPin1,1)
            pause(ydelay)
            writeDigitalPin(a,stepPin1,0)
            pause(ydelay)
        end
        %end of error check prior to move
        
        
        %now make the move
        
        %set the direction of xstepper
        writeDigitalPin(a,dirPin,1)
        
        %find the the number of loops required to move y stepper
        stopx = (app.indexx1)*kx;
        
        %move x stepper to initial loc by loops some some amount of
        %time
        steps = (stopx+xBias)
        for spotx=1:steps
            
            %run y motor, control by on and offing a pin interveniously
            writeDigitalPin(a,stepPin,1)
            pause(xdelay)
            writeDigitalPin(a,stepPin,0)
            pause(xdelay)
        end
        
        %same for y
        %set the direction of y stepper
        writeDigitalPin(a,dirPin1,1)
        
        %find the the steps to mvoe y
        stopy = (9.5-app.indexy1)*ky;
        
        %move y stepper to initial loc by loops some some amount of
        %time
        stepss = (stopy+yBias);
        for spoty=1:stepss
            
            %run y motor, control by on and offing a pin interveniously
            writeDigitalPin(a,stepPin1,1)
            pause(ydelay)
            writeDigitalPin(a,stepPin1,0)
            pause(ydelay)
        end
        
        %Now I am hovering over the right spot
        
        %Lower down some amount
        writePosition(s,.6)
        pause(downDelay)
        writePosition(s,.5)
        
        %Pause activate magnet
        writeDigitalPin(a,'D32',1)
        pause(1)
        
        %Raise it back some amount
        %slowly raise it
        writePosition(s,.47)
        pause(.33)
        writePosition(s,.4)
        pause(upDelay)
        writePosition(s,.55)
        pause(.015)
        writePosition(s,.5)
        
        %at this point, we have the piece in our grasp above board
        %move piece in x direction, by the difference between spots
        xmove = app.indexx2-app.indexx1;
        xmoveStop = xmove*kx;
        xmoveSpot = 0;
        
        if app.indexx2-app.indexx1 < 0
            writeDigitalPin(a,dirPin,0)
        end
        
        
        %run motor for x until it gets to new spot
        for xmoveSpot=1:xmoveStop
            
            %control the motor
            writeDigitalPin(a,stepPin,1)
            pause(xdelay)
            writeDigitalPin(a,stepPin,0)
            pause(xdelay)
        end
        
        %find the amount of change in y position
        ymove = app.indexy2 - app.indexy1;
        ymoveStop = ymove*ky;
        
        if app.indexy2-app.indexy1 >0
            writeDigitalPin(a,dirPin1,0)
        end
        
        
        %move piece to new y location
        for ymoveSpot=1:ymoveStop
            
            %run the motor of Y
            writeDigitalPin(a,stepPin1,1)
            pause(ydelay)
            writeDigitalPin(a,stepPin1,0)
            pause(ydelay)
        end
        
        %We at the dropoff location
        %first lower servo to the correct position
        writePosition(s,.6)
        pause(downDelay)
        writePosition(s,.5)
        
        %Let go off the piece
        pause(.25)
        writeDigitalPin(a,'D32',0)
        pause(.5)
        
        %as another check to make sure we let go shake servo
        %by moving the y stepper back and forth
        %but first raide it ever so slightly
        
        writePosition(s,.45)
        pause(.65)
        writePosition(s,.5)
        
        %moving y stepper
        earthquake = 0;
        
        %this is to shake the piece off
        
        for i=1:4
            writeDigitalPin(a,stepPin1,1)
            pause(.0000001)
            writeDigitalPin(a,stepPin1,0)
            pause(.0000001)
            writeDigitalPin(a,stepPin1,1)
            pause(.0000001)
            writeDigitalPin(a,stepPin1,0)
            pause(.0000001)
            
            writeDigitalPin(a,dirPin1,0)
            writeDigitalPin(a,stepPin1,1)
            pause(.00000001)
            writeDigitalPin(a,stepPin1,0)
            pause(.000000001)
            writeDigitalPin(a,stepPin1,0)
            pause(.000000001)
            writeDigitalPin(a,dirPin1,1)
            
            
        end


        %now raise servo to the correct position
        writePosition(s,.4)
        pause(upDelay)
        writePosition(s,.5)
        
        %We dropped the piece off, now return back 'home'
        
        %very important to switch the directtion of x
        writeDigitalPin(a,dirPin,0) ;
        
        %first return the x motor
        xmoveStop = app.indexx2*kx;
        
        
        %move x stepper home
        for xmoveSpot=1:xmoveStop+xBias
            
            writeDigitalPin(a,stepPin,1);
            pause(xdelay);
            writeDigitalPin(a,stepPin,0);
            pause(xdelay);
        end
        
        %move y stepper home
        ymoveStop = app.indexy2*ky;
        
        %very important to change the direction of y
        writeDigitalPin(a,dirPin1,0)
        
        %lmove y to its drop spot from pickup loc
        for ymoveSpot=1: (11.5*ky)-ymoveStop
            writeDigitalPin(a,stepPin1,1);
            pause(ydelay);
            writeDigitalPin(a,stepPin1,0);
            pause(ydelay);
        end
        
    end


    function results = takePiece(app)
        %same exact as before,e xcept now change the start and end loc
        %clear and create the object
        clear a
        a = arduino();
        
        %create object from z motion, servo
        s = servo(a,'D4',"MinPulseDuration",7.000e-4,"MaxPulseDuration",.00125);
        
        % define the pins for x stepepr
        stepPin = 'D6';
        dirPin = 'D26';
        
        %assign the pins for y stepper, assing the tag 1 after name
        stepPin1 = 'D3';
        dirPin1 = 'D24';
        
        %configrue pin modes for x stepper
        configurePin(a,dirPin,'DigitalOutput')
        
        %confugure pin modes for y stepper
        configurePin(a,dirPin1,'DigitalOutput')
        
        %define the down and up delay
        downDelay = 2.294;
        upDelay = 4.0;
        
        %set a constant value for the steps to transpose 1 square
        kx = 16;
        ky = 22;
        
        %set the bias, since i move based on index -1, bias is to first
        %square.
        yBias = 15;
        xBias = 0;
        
        %set the x and y speed, but writing the delay
        xdelay = .000000000000000000009;
        ydelay = .000000000000002;
        
        
        %getmoving,first do an error check and move the machine home more
        writeDigitalPin(a,dirPin,0)
        for spotx=1:10
            
            
            %set the direction of xstepper
            writeDigitalPin(a,stepPin,1)
            pause(xdelay)
            writeDigitalPin(a,stepPin,0)
            pause(xdelay)
        end
        
        %same erroer check for y stepper
        writeDigitalPin(a,dirPin1,0)
        for spotx=1:10
            
            
            %set the direction of xstepper
            writeDigitalPin(a,stepPin1,1)
            pause(ydelay)
            writeDigitalPin(a,stepPin1,0)
            pause(ydelay)
        end
        %end of error check prior to move
        
        
        %we are heading to the taken piece
        
        
        %find steps bettween
        stopx = (app.indexx2)*kx;
        
        %set the direction of the X Stepper
        writeDigitalPin(a,dirPin,1);
        
        %step X a certain amount
        for spotx=1:(stopx)
            
            %set the stepper by switching a pin with some delay
            writeDigitalPin(a,stepPin,1);
            pause(xdelay);
            writeDigitalPin(a,stepPin,0);
            pause(xdelay);
            
        end
        
        %Now apply same technique for y stepper to go to taken piece
        pause(1)
        
        %set the direction of y stepper
        writeDigitalPin(a,dirPin1,0)
        
        %find the the number of loops required to move y stepper
        stopy = (app.indexy2)*ky
        
        %move y stepper
        for spoty=1:(stopy+yBias)
            
            %run y motor, control by on and offing a pin interveniously
            writeDigitalPin(a,stepPin1,1)
            pause(ydelay)
            writeDigitalPin(a,stepPin1,0)
            pause(ydelay)
            
        end
        
        
        %Now I am hovering over the right spot
        
        %Lower down some amount
        writePosition(s,.6)
        pause(downDelay)
        writePosition(s,.5)
        
        %Pause activate magnet
        writeDigitalPin(a,'D32',1)
        pause(.75)
        
        %Raise it back some amount
        %slowly raise it
        writePosition(s,.47)
        pause(.33)
        writePosition(s,.4)
        pause(upDelay)
        writePosition(s,.55)
        pause(.015)
        writePosition(s,.5)
        
        
        %at this point, we have the piece in our grasp above board
        %now to go drop if off in the bucket
        %move piece in x direction, by the difference between spots
        xmoveStop = (13-app.indexx2)*kx
        
        %run motor for x until it gets to new spot
        for i=1:xmoveStop
            
            %control the motor
            writeDigitalPin(a,stepPin,1)
            pause(xdelay)
            writeDigitalPin(a,stepPin,0)
            pause(xdelay)
            
        end
        
        %find the amount of change in y position
        ymoveStop = (13-app.indexy2)*ky
        
        %move piece to new y location of bucket
        for i=1:ymoveStop
            
            %run the motor
            writeDigitalPin(a,stepPin1,1)
            pause(ydelay)
            writeDigitalPin(a,stepPin1,0)
            pause(ydelay)
            
        end
        
        
        %We at the dropoff location
        
        
        %first lower servo to the correct position
        writePosition(s,.6)
        pause(downDelay)
        writePosition(s,.5)
        
        %Let go off the piece
        pause(.25)
        writeDigitalPin(a,'D32',0)
        pause(.5)
        
        %as another check to make sure we let go shake servo
        %by moving the y stepper back and forth
        %but first raide it ever so slightly
        
        writePosition(s,.45)
        pause(.65)
        writePosition(s,.5)
        
        %moving y stepper
        earthquake = 0;
        
        %this is to shake the piece off
        
        for i=1:4
           
           
            pause(.0000001)
            writeDigitalPin(a,stepPin1,0)
            pause(.0000001)
            writeDigitalPin(a,stepPin1,1)
            pause(.0000001)
            writeDigitalPin(a,stepPin1,0)
            pause(.0000001)
            
            writeDigitalPin(a,stepPin1,1)
            writeDigitalPin(a,stepPin1,1)
            pause(.00000001)
            writeDigitalPin(a,stepPin1,0)
            pause(.000000001)
            writeDigitalPin(a,stepPin1,0)
            pause(.000000001)
            writeDigitalPin(a,dirPin1,1)
            
            
        end


        %now raise servo to the correct position
        writePosition(s,.4)
        pause(upDelay)
        writePosition(s,.5)


        %We dropped the piece off, now return back 'home'
        
        %very important to switch the directtion of x
        writeDigitalPin(a,dirPin,0)
        
        %first return the x motor
        xmoveStop = app.indexx2*kx
        
        %move x stepper home
        for j=1:xmoveStop
            writeDigitalPin(a,stepPin,1)
            pause(xdelay)
            writeDigitalPin(a,stepPin,0)
            pause(xdelay)
            
        end
        
        %move y stepper home
        ymoveStop = app.indexy2*ky
        
        %very important to change the direction of y
        writeDigitalPin(a,dirPin1,0)
        
        %move y home
        for k=1:(ymoveStop+yBias)
            writeDigitalPin(a,stepPin1,1)
            pause(ydelay)
            writeDigitalPin(a,stepPin1,0)
            pause(ydelay)
            
            results =0
        end
        
    end
    end


%             
%             %%Section for side stepepr
%             %for each one of the indexes, in the x and y direction, I need
%             %to have a function to control that properly. 
%             %for starters I will try to multiply the index by a constnat,
%             %and use the product as my end condition
%             %define the cosntant, the number of steps per square
%             k = 22  
%             
%             stopx = app.indexx1*k
%             
%             
%             
%             
%            
%             spot = 0
%             
%             writeDigitalPin(a,dirPin,1) %this sets the dire
%              
%             %go clockwise to a certain point
%             while spot < (stopx+40)
%              
%                 writeDigitalPin(a,stepPin,1) %turns on the step pin
%                 pause(.02)     %20 milisecond
%                 writeDigitalPin(a,stepPin,0) %turns off the step pin
%                 pause(.02)
%                 
%                 spot = spot+1
%             end
%             
%             %very important to clear the arduino object if not in use 
%            


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, first_move, last_move, total_turns, stateofturn, old_icon)
            %I could here display something for the users to enter their name and pick
            %their character icon, in future it could support other modes like playing
            %againt snapfis
            
            %give some initial values to the app variables
            
            app.stateofturn = 0;        %used to determine wether if the user is in the first or second part of their move (grabbing or placing an icon)
            app.splayturn = 0;          %state of player turn is a variable that will tells the app wether the player should be making their moves, or if the bot is making its move
            app.mode = 0;               %In one mode we have 2 player game supported, in the other we have the Ai version supported
            app.tempmes= ('nothinggoodafter2am');       %arbitruary message, variable used to swap messages after the turn
            app.firstpiece =  ("colorpiece.png");      %arbiturary name
            app.destpiece = ("destcolorandpiece.png");     %arbituraury name
            app.firstpiecevalue = 0;          %initally no piece is selected.
            app.finalloc = ('final location');
            app.inspot = ("initial location");
            
            
            %app.a = arduino();
          
            
            %Here is where we get the user input, we have them select mode (2 player game or against the AI), as well as input their name
            
            prompt3 = "Welcome to chessAPP! To play against the computer, enter 1, or enter 2 to play a 2 player local match. Type the number and then press enter."; %welcoming message
            app.mode = input(prompt3);               %save there resoonse in mode variable
            
            if app.mode == 1
                prompt1 = "Enter your name as you'd like it to appear!";            %Ask for their name, as a string
                app.Player1 = input(prompt1,'s');                                   %Call the above string and save response
                app.Player1Label.Text = app.Player1;                                %Place the name on the black side of the table.
                
                app.Player2Label.Text = "T-Bot";                %set the white said the computers name
                
            elseif app.mode == 2          %this is if they select two player mode
                prompt1 = "PLayer black, moving second - Enter your name as you'd like it to appear!";            %Ask for their name, as a string
                app.Player1 = input(prompt1,'s');                                   %Call the above string and save response
                app.Player1Label.Text = app.Player1;                                %Place the name on the black side of the table.
                
                prompt2 = "Player white, moving first - Enter your name as you'd like it to appear";        %prepare string
                app.Player2 = input(prompt2,'s');  %call the above string
                app.Player2Label.Text = app.Player2;       %label the white side appropriately
                
            else
                %should return to above prompt 3
            end
            
            %this is for getting the user messaging to work
            %mes1 = ('Wait for other player');       %if it isnt your turn
            %mes2 = ('Your turn!');          %if it is their turn
            
            % app.p1label.Text = mes1;            %set label accordingly, player 1, on bottom aka black , is waiting first
            % app.p2label.Text = mes2;                %player 2 , white, opens and therefor is told it is their turn
            
            %since we want Tbot going first, gien the bestchance for us to
            %win and execuute quick, impressive openers.
            
            app.SUBMITMOVEButton.Text = "Start Game" ;  %this is how the game starts, timer begins after pressed
        end

        % Callback function: Button_A1, Button_A2, Button_A3, 
        % Button_A4, Button_A5, Button_A6, Button_A7, Button_A8, 
        % Button_B1, Button_B2, Button_B3, Button_B4, Button_B5, 
        % Button_B6, Button_B7, Button_B8, Button_C1, Button_C2, 
        % Button_C3, Button_C4, Button_C5, Button_C6, Button_C7, 
        % Button_C8, Button_D1, Button_D2, Button_D3, Button_D4, 
        % Button_D5, Button_D6, Button_D7, Button_D8, Button_E1, 
        % Button_E2, Button_E3, Button_E4, Button_E5, Button_E6, 
        % Button_E7, Button_E8, Button_F1, Button_F2, Button_F3, 
        % Button_F4, Button_F5, Button_F6, Button_F7, Button_F8, 
        % Button_G1, Button_G2, Button_G3, Button_G4, Button_G5, 
        % Button_G6, Button_G7, Button_G8, Button_H1, Button_H2, 
        % Button_H3, Button_H4, Button_H5, Button_H6, Button_H7, 
        % Button_H8, UIFigure
        function yourturns(app, event)
            %This function will run everytime a button on the board is pushhed!, they
            %all share this same value return function
            % this is for pressing buttons to select a piece to pick up and then
            %select where to place it
            
            if rem(app.stateofturn,2) ==  0         %if they have not yet moved, or they are on an even number aka, back to start of their turn
                app.old_icon = event.Source.Icon;        %save the icon of the first button
                
                
                app.inspot = event.Source.Layout;
                app.inrow = app.inspot.Row;
                app.incol = app.inspot.Column;
                app.ranksboard(app.inrow,app.incol) = 0;      %this is
                set(event.Source, 'Icon', 'remove')     %remove the Icon of the button selected
                
            else            %the case that it is the second part of the move


                set(event.Source, 'Icon', app.old_icon)       %assign the icon to the old icon here, this is how to acceces the laayout easily, will be usefull when updating the icons
                app.endspot = event.Source.Layout;
                app.endrow = app.endspot.Row;
                app.endcol = app.endspot.Column;
                
                %here would be a fanatstic time to call a function
                %that takes in the current board as an input, and then
                %outputs a matirx with each "value" of its corresponding
                %icon in the app, will then later use a function to
                %interpret that data based on the first button pushed (and
                %then it should output all the places that the piece is
                %allowed to move). The computer bot, when it is their turn,
                %is simply going to use some logic and creativty to rank
                %the moves according to the values in the matrix.
                
                scanboard(app)
                getranksboard(app)
                
                
            end
            
            app.stateofturn = app.stateofturn + 1;       %increment the state of the turn each time
            
            
        end

        % Button pushed function: SUBMITMOVEButton
        function SUBMITMOVEButtonPushed(app, event)
            %notice the app is intially labeled start
            app.SUBMITMOVEButton.Text = "SUBMIT MOVE";
            
            %This happens right after the subbit move button is pressed! -
            %If they are playing against the computer, then this only will
            %happen when the player makes there move, therefor this can
            %notify us it is the computers turn, we have a built in paus
            %before it starts that
            
            mes1 = ('Wait for other player');       %reintiliaze the messae
            mes2 = ('Your turn!');                  %reinitialize the meesagwe
            
            app.tempmes = app.p1label.Text;      %save whatever is currently in the player 1 message box
            app.p1label.Text = app.p2label.Text;         %replace it with whatever is in the player 2 message box
            app.p2label.Text = app.tempmes;          %swap by placing the origainal saved variable for player 1 and putting it into player 2s message box


            if app.mode == 1 %conidtion for computer v player
                %The button was just pressed in order to start the game
                
                
                %labeling while the bot is making a move
                app.p1label.Text = mes1;
                app.p2label.Text = mes2;
                
                %so run run the scan and ranks program
                
                scanboard(app)  %get the board with string values for names of pieces
                getranksboard(app)  %convert to their point value
                getnumpieces(app)   %counts the number of white or black pieces left
                getoptions(app)    %show all the moves for a given turn, going piece by piece and turn by turn.
                makemove(app)   %updates the app
                
                check = app.ranksboard(app.indexx2,app.indexy2);
                
                  if check > 0
                    takePiece(app)
                 end
                 movePiece(app)
           
                %the other players turn, (use these messages as a check for
                %whose turn it is later
                app.p1label.Text = mes2;
                app.p2label.Text = mes1;
                
                
            end
            
            %For now, lets have the Bot hard coded a scilian opener.
            %                 if app.splayturn == 1       %this is the case where it is the first move of the game
            %
            %                     pause(1)                %wait for 2 seconds
            %
            %
            %                     %call bot function here.
            %
            %                     app.firstpiece = app.Button_D7.Icon;
            %                     set(app.Button_D7, 'Icon', 'remove')        %remove desired icon
            %
            %                     app.destpiece = app.Button_D5.Icon;
            %                     set(app.Button_D5, 'Icon', 'whitepawn.png')     %update to the new location
            %
            %                     %start first case for updating the baord.
            %
            %                     if app.firstpiece == "whitepawn.png"
            %                         app.firstpiecevalue = 1.2;
            %                     end
            %
            %                     app.finalloc = app.Button_D5;
            %
            %                     if app.finalloc == app.Button_D5
            %                         app.board(2,4) = 0;
            %                         app.board(4,4) = app.firstpiecevalue;
            %                     end
            %
            %
            %                 elseif app.splayturn == 2       %now this would be the computers turn to make a second move, since the submit move button would have only been clicked once by the other
            %                     pause(1)            %wait for 2 seconds
            %                     set(app.Button_C8, 'Icon', 'remove')        %remove desired icon
            %                     set(app.Button_F5, 'Icon', 'whitebishop.png')     %set icon to desired location
            %
            %                     scanboard(app)
            %                     getranksboard(app)
            %
            %
            %                 elseif app.splayturn == 3
            %                     pause(1)
            %                     set(app.Button_E8, 'Icon', 'remove')        %remove desired icon
            %                     set(app.Button_C6, 'Icon', 'whitequeen.png')
            %
            %                 elseif app.splayturn == 4
            %                     pause(1)
            %                     set(app.Button_C6, 'Icon', 'remove')        %remove desired icon
            %                     set(app.Button_C2, 'Icon', 'whitequeen.png')
            %
            %
            %                 end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @yourturns, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.chessboardfig = uigridlayout(app.GridLayout);
            app.Button_A8 = uibutton(app.chessboardfig, 'state');
            app.Button_A8.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_A8.Icon = 'whiterook.png';
            app.Button_A8.Text = '';
            app.Button_B8 = uibutton(app.chessboardfig, 'state');
            app.Button_B8.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_B8.Icon = 'whiteknight.png';
            app.Button_B8.Text = '';
            app.Button_C8 = uibutton(app.chessboardfig, 'state');
            app.Button_C8.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_C8.Icon = 'whitebishop.png';
            app.Button_C8.Text = '';
            app.Button_D8 = uibutton(app.chessboardfig, 'state');
            app.Button_D8.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_D8.Icon = 'whiteking.png';
            app.Button_D8.Text = '';
            app.Button_E8 = uibutton(app.chessboardfig, 'state');
            app.Button_E8.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_E8.Icon = 'whitequeen.png';
            app.Button_E8.Text = '';
            app.Button_F8 = uibutton(app.chessboardfig, 'state');
            app.Button_F8.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_F8.Icon = 'whitebishop.png';
            app.Button_F8.Text = '';
            app.Button_G8 = uibutton(app.chessboardfig, 'state');
            app.Button_G8.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_G8.Icon = 'whiteknight.png';
            app.Button_G8.Text = '';
            app.Button_H8 = uibutton(app.chessboardfig, 'state');
            app.Button_H8.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_H8.Icon = 'whiterook.png';
            app.Button_H8.Text = '';
            app.Button_A7 = uibutton(app.chessboardfig, 'state');
            app.Button_A7.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_A7.Icon = 'whitepawn.png';
            app.Button_A7.Text = '';
            app.Button_B7 = uibutton(app.chessboardfig, 'state');
            app.Button_B7.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_B7.Icon = 'whitepawn.png';
            app.Button_B7.Text = '';
            app.Button_C7 = uibutton(app.chessboardfig, 'state');
            app.Button_C7.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_C7.Icon = 'whitepawn.png';
            app.Button_C7.Text = '';
            app.Button_D7 = uibutton(app.chessboardfig, 'state');
            app.Button_D7.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_D7.Icon = 'whitepawn.png';
            app.Button_D7.Text = '';
            app.Button_E7 = uibutton(app.chessboardfig, 'state');
            app.Button_E7.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_E7.Icon = 'whitepawn.png';
            app.Button_E7.Text = '';
            app.Button_F7 = uibutton(app.chessboardfig, 'state');
            app.Button_F7.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_F7.Icon = 'whitepawn.png';
            app.Button_F7.Text = '';
            app.Button_G7 = uibutton(app.chessboardfig, 'state');
            app.Button_G7.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_G7.Icon = 'whitepawn.png';
            app.Button_G7.Text = '';
            app.Button_H7 = uibutton(app.chessboardfig, 'state');
            app.Button_H7.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_H7.Icon = 'whitepawn.png';
            app.Button_H7.Text = '';
            app.Button_A6 = uibutton(app.chessboardfig, 'state');
            app.Button_A6.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_A6.Text = '';
            app.Button_B6 = uibutton(app.chessboardfig, 'state');
            app.Button_B6.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_B6.Text = '';
            app.Button_C6 = uibutton(app.chessboardfig, 'state');
            app.Button_C6.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_C6.Text = '';
            app.Button_D6 = uibutton(app.chessboardfig, 'state');
            app.Button_D6.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_D6.Text = '';
            app.Button_E6 = uibutton(app.chessboardfig, 'state');
            app.Button_E6.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_E6.Text = '';
            app.Button_F6 = uibutton(app.chessboardfig, 'state');
            app.Button_F6.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_F6.Text = '';
            app.Button_G6 = uibutton(app.chessboardfig, 'state');
            app.Button_G6.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_G6.Text = '';
            app.Button_H6 = uibutton(app.chessboardfig, 'state');
            app.Button_H6.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_H6.Text = '';
            app.Button_A5 = uibutton(app.chessboardfig, 'state');
            app.Button_A5.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_A5.Text = '';
            app.Button_B5 = uibutton(app.chessboardfig, 'state');
            app.Button_B5.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_B5.Text = '';
            app.Button_C5 = uibutton(app.chessboardfig, 'state');
            app.Button_C5.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_C5.Text = '';
            app.Button_D5 = uibutton(app.chessboardfig, 'state');
            app.Button_D5.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_D5.Text = '';
            app.Button_E5 = uibutton(app.chessboardfig, 'state');
            app.Button_E5.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_E5.Text = '';
            app.Button_F5 = uibutton(app.chessboardfig, 'state');
            app.Button_F5.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_F5.Text = '';
            app.Button_G5 = uibutton(app.chessboardfig, 'state');
            app.Button_G5.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_G5.Text = '';
            app.Button_H5 = uibutton(app.chessboardfig, 'state');
            app.Button_H5.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_H5.Text = '';
            app.Button_A4 = uibutton(app.chessboardfig, 'state');
            app.Button_A4.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_A4.Text = '';
            app.Button_B4 = uibutton(app.chessboardfig, 'state');
            app.Button_B4.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_B4.Text = '';
            app.Button_C4 = uibutton(app.chessboardfig, 'state');
            app.Button_C4.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_C4.Text = '';
            app.Button_D4 = uibutton(app.chessboardfig, 'state');
            app.Button_D4.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_D4.Text = '';
            app.Button_E4 = uibutton(app.chessboardfig, 'state');
            app.Button_E4.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_E4.Text = '';
            app.Button_F4 = uibutton(app.chessboardfig, 'state');
            app.Button_F4.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_F4.Text = '';
            app.Button_G4 = uibutton(app.chessboardfig, 'state');
            app.Button_G4.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_G4.Text = '';
            app.Button_H4 = uibutton(app.chessboardfig, 'state');
            app.Button_H4.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_H4.Text = '';
            app.Button_A3 = uibutton(app.chessboardfig, 'state');
            app.Button_A3.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_A3.Text = '';
            app.Button_B3 = uibutton(app.chessboardfig, 'state');
            app.Button_B3.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_B3.Text = '';
            app.Button_C3 = uibutton(app.chessboardfig, 'state');
            app.Button_C3.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_C3.Text = '';
            app.Button_D3 = uibutton(app.chessboardfig, 'state');
            app.Button_D3.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_D3.Text = '';
            app.Button_E3 = uibutton(app.chessboardfig, 'state');
            app.Button_E3.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_E3.Text = '';
            app.Button_F3 = uibutton(app.chessboardfig, 'state');
            app.Button_F3.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_F3.Text = '';
            app.Button_G3 = uibutton(app.chessboardfig, 'state');
            app.Button_G3.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_G3.Text = '';
            app.Button_H3 = uibutton(app.chessboardfig, 'state');
            app.Button_H3.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_H3.Text = '';
            app.Button_A2 = uibutton(app.chessboardfig, 'state');
            app.Button_A2.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_A2.Icon = 'blackpawn.png';
            app.Button_A2.Text = '';
            app.Button_B2 = uibutton(app.chessboardfig, 'state');
            app.Button_B2.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_B2.Icon = 'blackpawn.png';
            app.Button_B2.Text = '';
            app.Button_C2 = uibutton(app.chessboardfig, 'state');
            app.Button_C2.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_C2.Icon = 'blackpawn.png';
            app.Button_C2.Text = '';
            app.Button_D2 = uibutton(app.chessboardfig, 'state');
            app.Button_D2.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_D2.Icon = 'blackpawn.png';
            app.Button_D2.Text = '';
            app.Button_E2 = uibutton(app.chessboardfig, 'state');
            app.Button_E2.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_E2.Icon = 'blackpawn.png';
            app.Button_E2.Text = '';
            app.Button_F2 = uibutton(app.chessboardfig, 'state');
            app.Button_F2.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_F2.Icon = 'blackpawn.png';
            app.Button_F2.Text = '';
            app.Button_G2 = uibutton(app.chessboardfig, 'state');
            app.Button_G2.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_G2.Icon = 'blackpawn.png';
            app.Button_G2.Text = '';
            app.Button_H2 = uibutton(app.chessboardfig, 'state');
            app.Button_H2.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_H2.Icon = 'blackpawn.png';
            app.Button_H2.Text = '';
            app.Button_A1 = uibutton(app.chessboardfig, 'state');
            app.Button_A1.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_A1.Icon = 'blackrook.png';
            app.Button_A1.Text = '';
            app.Button_B1 = uibutton(app.chessboardfig, 'state');
            app.Button_B1.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_B1.Icon = 'blackknight.png';
            app.Button_B1.Text = '';
            app.Button_C1 = uibutton(app.chessboardfig, 'state');
            app.Button_C1.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_C1.Icon = 'blackbishop.png';
            app.Button_C1.Text = '';
            app.Button_D1 = uibutton(app.chessboardfig, 'state');
            app.Button_D1.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_D1.Icon = 'blackking.png';
            app.Button_D1.Text = '';
            app.Button_E1 = uibutton(app.chessboardfig, 'state');
            app.Button_E1.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_E1.Icon = 'blackqueen.png';
            app.Button_E1.Text = '';
            app.Button_F1 = uibutton(app.chessboardfig, 'state');
            app.Button_F1.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_F1.Icon = 'blackbishop.png';
            app.Button_F1.Text = '';
            app.Button_G1 = uibutton(app.chessboardfig, 'state');
            app.Button_G1.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_G1.Icon = 'blackknight.png';
            app.Button_G1.Text = '';
            app.Button_H1 = uibutton(app.chessboardfig, 'state');
            app.Button_H1.ValueChangedFcn = createCallbackFcn(app, @yourturns, true);
            app.Button_H1.Icon = 'blackrook.png';
            app.Button_H1.Text = '';
            app.Player1Label = uilabel(app.GridLayout);
            app.Player1Label.Text = 'Player1';
            app.Player2Label = uilabel(app.GridLayout);
            app.Player2Label.Text = 'Player2';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SUBMITMOVEButton = uibutton(app.GridLayout3, 'push');
            app.SUBMITMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITMOVEButtonPushed, true);
            app.SUBMITMOVEButton.Text = 'SUBMIT MOVE';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = '';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.p2label = uilabel(app.GridLayout);
            app.p1label = uilabel(app.GridLayout);
            app.p1label.Text = 'Label2';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChessAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end